classdef Project_GUI_Analyze_Calcium < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalyzeCalciumImagingUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TimeTab                        matlab.ui.container.Tab
        NeedInstructionsButton         matlab.ui.control.Button
        EndTimeEditField               matlab.ui.control.NumericEditField
        EndTimeEditFieldLabel          matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        WhichChannelisCalciumDropDown  matlab.ui.control.DropDown
        WhichChannelisCalciumDropDownLabel  matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        Write1ifyouwantwholefileanalyzedLabel  matlab.ui.control.Label
        TimeslicenumberSlider          matlab.ui.control.Slider
        TimeslicenumberSliderLabel     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        BackgroundTab                  matlab.ui.container.Tab
        RedrawBackgroundButton         matlab.ui.control.Button
        NeedInstructionsButton_2       matlab.ui.control.Button
        PlotSelectedBackgroundButton   matlab.ui.control.Button
        DoneButton_2                   matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        SelectingCellsTab              matlab.ui.container.Tab
        NeedInstructionsButton_3       matlab.ui.control.Button
        AnalyzeSelectedCellsButton     matlab.ui.control.Button
        RedoCellSelectionButton        matlab.ui.control.Button
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        YESca
        useMask
        NOTca
        path
        timestart
        timeend
        images
        newtime
        slice
        newimage
        image1
        image2
        mydata
        whichfile
        XY
        totaltime
        whichchannelca
        bkgcell
        st
        ed
        MaxIntensityMap
        AverageIntensityMap
        DataOut
        forplotting
        calciumchannel
        RawImg
        T
        overlay
        NOTcalciumchannel
        imagesnosignal
        MeanNoSignalMask 
        MeanNoSignalMask0
        averageamp
        pixelintensities
        sx
        sy
        sz
        imagesRaw
        bfpath
        bkgROI
        bckimHan
        calciumimages
        NOTcalciumimages
        mask
        cellmask
        threshhold
        imagescell
        changebounds
    end
    
    
 methods (Access = public)
        
       function replot(app)

        %this function replots the image for it to match the slider value.

        %first get two snapshots of the images. one is for channel 1, one
        %is for channel 2
        app.image1 = app.slice(:,:,(app.newtime));
        app.image2 = app.slice(:,:,(app.newtime + 1));

        %overlay the 2 snapshots and show them
        app.newimage= imfuse(app.image1, app.image2);
        imshow(app.newimage, 'Parent', app.UIAxes);
 
            end
          
        
       function background(app);
            %% APPLYING "SILENT CELL" THRESHOLD 
                run("background.m"); %this script allows masks to be created and figures to be drawn on.
             
      
                load("vars.mat");  %variables from the background.m code that contain the background masks in DataOut struct.

                %loading DataOut variables into the GUI properties. 
                app.bkgcell = DataOut.bkgcell;
                app.bkgROI = DataOut.bkgROI;
                
                %overlaying the mask over the average intensity map and
                %showing on background axes.
               %%overlay = imoverlay(mat2gray(app.AverageIntensityMap), app.bkgcell, 'colormap', 'jet', 'facealpha', .75, 'ZeroAlpha', 0);
               overlay = imoverlay(mat2gray(app.AverageIntensityMap), app.bkgcell);
               imshow(overlay, 'Parent', app.UIAxes4);
                
              
                app.TabGroup.SelectedTab = app.BackgroundTab; %changes tab to background tab to see background
                %once the user cliks "plot" button, it will be plotted.

       end

       
       function boxforpath(app)

            %allos bfopen() path to be used wherever it is on one's
            %computer by adding that path.
            f1 = msgbox("Please select a file within the bfmatlab toolbox folder");
            
            uiwait(f1);
            
            [file app.bfpath] = uigetfile();
            addpath(app.bfpath);
           
       end

   end


    methods (Access = public)
        
        function cellselection(app);
            

            run("cellselect.m");  %this script allows a figure vs. UIFigure to appear in order to draw cells with drawfreehand() and create masks for them

            load("vars.mat"); %again, loads the exports of cell masks from script above. Stored in struct DataOut.

            app.cellmask = DataOut.cellmask;
            app.mask = DataOut.mask;
         
            app.useMask = DataOut.useMask;
            
            
            %create colormap of the cell selections and overlay them into
            %av. intensity map for visualization.
            %cmap = hsv(max(max(app.mask)));
          

            theoverlay = imoverlay(mat2gray(app.AverageIntensityMap), app.mask);
            imshow(theoverlay, 'Parent', app.UIAxes5); %showing in axes.
            %clim([1 5]);
            %colormap(cmap)
            %colorbar; %puts colorbar from cmap colormap.

            %tell the user to check cells in messsage:
            msgbox("Cells have been selected. Confirm your choice in Selecting Cells Tab"); 
            app.TabGroup.SelectedTab = app.SelectingCellsTab;
            
            %initializing variables:
            app.forplotting = [];
            forticks = [];
            %starting a loop the size of how many cells you selected:
            for i=1:size(app.cellmask,3);
                %using cellmasks from other script, now we multiply that area by all the
                %images in time dimension
                    for j=1:size(app.calciumimages,3);
                        app.forplotting(:,:,j) = double(app.calciumimages(:,:,j)).* app.cellmask(:,:,i);
                    end 
                    
                    %forplotting has the pixels, the average of the x and y values are the
                    %intensities.
                    app.pixelintensities = squeeze(mean(mean(app.forplotting, 1),2));
                    
                    %now plotting: first creating variables for plotting
                    
                    time = app.T.'; %time needs to be transposed to plot
                    
                    double(app.pixelintensities); %turn into double
              
            
                    plot(1:size(app.pixelintensities), app.pixelintensities, 'Parent', app.UIAxes6, 'Color', rand(1,3)); %plotting them into the axis
                    %plot and hold for all pther lines
                    hold(app.UIAxes6,'on');
                    [forticks] = [forticks  ; app.pixelintensities];
            
            end 

            [minPx,maxPx] = bounds(forticks);

            app.UIAxes6.YTick = [minPx maxPx];

            app.UIAxes6.YTickLabel = {num2str(minPx) , num2str(maxPx)};
            hold(app.UIAxes6,'off');


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%need to set limits or the images dont fit in the axis objects.
app.UIAxes.XLim = [1 512]
app.UIAxes2.XLim = [1 512]
app.UIAxes3.XLim = [1 512]
app.UIAxes4.XLim = [1 512]

close all %%%need space so need to close all


app.threshhold = 2  %this is average threshold limits

app.UIAxes.YLim = [1 512]
app.UIAxes2.YLim = [1 512]
app.UIAxes3.YLim = [1 512]
app.UIAxes4.YLim = [1 512]

%starts variables for initial plotting
app.timestart = 2;  
app.timeend = 3;
app.changebounds =[];
         
 %%%%%%%%%opening the document and arranging pixels per slice
boxforpath(app);   %start the messagebox function to get path for bfopen().

%starts messagebox to select file for analysis.
f2 = msgbox("Please select the file you want to analyze. Must be .czi type.");

uiwait(f2);
[app.whichfile, app.path] = uigetfile("*.czi");

%opens file with bioformats, and catches the error if path is wrong.
try 

app.mydata = bfopen([app.path app.whichfile]);
ME1 = MException.last;

catch ME1;

   if contains(ME1.identifier, 'Undefined function ''bfopen'' for input arguments of type ''char''')
    warning('Your file or bfopen() toolbox is not in an accesible place. Please move files and try again.')
  
   elseif contains(ME1.identifier, 'MATLAB:MException:MustCallLastFromCommandLine');
       %if there are no errors, it iterated through file data into slice
       
            app.XY = app.mydata{1,1};
            for k=1:length(app.XY);
               app.slice(:,:,k) = app.mydata{1,1}{k,:};
            end
            %creates av. intensity map for visualization in 3rd dimension.
            app.AverageIntensityMap = mean(app.slice, 3);

            app.newimage = app.AverageIntensityMap;

            %shows the initial image to pick time.
            imagesc(app.newimage, 'Parent', app.UIAxes);

            %slider limits based on the length of "time" of file.
            app.TimeslicenumberSlider.Limits = [1 size(app.slice, 3)];
            app.totaltime = size(app.slice, 3);
            app.TimeslicenumberSlider.MajorTicks = [1   app.totaltime/10  app.totaltime/10*2  app.totaltime/10*3  app.totaltime/10*4  app.totaltime/10*5 app.totaltime/10*6 app.totaltime/10*7 app.totaltime/10*8 app.totaltime/10*9   app.totaltime];
            app.TimeslicenumberSlider.MajorTickLabels = {'1',  num2str(app.totaltime/10),  num2str(app.totaltime/10*2),  num2str(app.totaltime/10*3), num2str(app.totaltime/10*4),  num2str(app.totaltime/10*5), num2str(app.totaltime/10*6), num2str(app.totaltime/10*7), num2str(app.totaltime/10*8), num2str(app.totaltime/10*9),  num2str(app.totaltime)};    
   else
     rethrow(ME1)
  end 
       
end 

%prevents the figures from being moved around
disableDefaultInteractivity(app.UIAxes4);
disableDefaultInteractivity(app.UIAxes3);
disableDefaultInteractivity(app.UIAxes2);
disableDefaultInteractivity(app.UIAxes_2);
disableDefaultInteractivity(app.UIAxes);

%this is to save and recall the path and file from workspace.
assignin("base", 'path', app.path);
assignin("base", 'whichfile', app.whichfile);

%makes slices into doubles to use easily
double(app.slice);


        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)

%this part starts the variables to divide the time by channel.

%timestart is simple, it's just the value on slider.
app.timestart = app.StartTimeEditField.Value; %cant start at 1 because then 1 becomes 0.5.
if app.timestart == 1
    app.timestart = 2
end 
%error checking if user picks a number higher than possible, it uses the
%max time as time end.
if app.EndTimeEditField.Value > app.totaltime;
    app.timeend = app.totaltime;
else
    app.timeend = app.EndTimeEditField.Value;
end

%if user picks -1, then whole time is analyzed.
if app.timestart > -1
    app.st = app.timestart;
else
    app.st=1;
end

if app.timeend > -1;
    app.ed = app.timeend;
else
    app.ed=size(app.slice,3);
end

%if timestart is even, then Ca channel is the second one (if it's #1)

%app.images is the slice file from start to end. 
app.images = app.slice(:,:,app.st:app.ed);

app.T = app.st:app.ed;  %app.T is now total time


 %now which channel is calcium?        
 %if timestart is even, then Ca channel is the second one (if it's #1)
 %notcalciumuimages is the opposite.

NOTca = 2; %initializing variables
YESca = 1 ;
            
if app.WhichChannelisCalciumDropDown.Value == 'Channel 2';
       if rem(app.timestart,2 ) == 0
         app.YESca = 2;
         app.NOTca = 1;
       else 
         app.YESca = 1;
         app.NOTca = 2;
       end 
else 
        if rem(app.timestart, 2) ~= 0  ;
             app.YESca = 2;
             app.NOTca = 1;
        else 
         app.YESca = 1;
         app.NOTca = 2;
        end 
end 


%% DECLARING IMAGE PROPERTIES
%size of x,y and z dimensions in variables.

app.sx= size(app.images,1); %x dim
app.sy= size(app.images,2); %y dim
app.sz= size(app.images,3); %x dim, time dim


app.imagesRaw= app.slice; %this is just to keep a copy of raw images 

%calciumimages is the start-end file, but only the calcium images.

app.calciumimages = app.images(:,:, app.YESca:2:end);

app.NOTcalciumimages = app.images(:,:,app.NOTca:2:end);

%now this plots the "background image" in the next tab (background tab).
app.bckimHan = imagesc(app.AverageIntensityMap, 'Parent', app.UIAxes4)

%now this plots the channel images averaging in the 3rd dim. (time).
forshow = mean(app.calciumimages, 3);
imagesc(forshow, 'Parent', app.UIAxes2);
axis fill
forshow2 = mean(app.NOTcalciumimages, 3);
imagesc(forshow2, 'Parent', app.UIAxes3);
axis fill

%DataOut is a struct with all the information for export. It's initialized.
app.DataOut.app.images= app.images;

%initializing variable:
app.forplotting = zeros(size(app.calciumimages, 1), size(app.calciumimages,2), size(app.calciumimages,3));


%third messagebox to tell the user to switch tabs. 
msgbox("Draw a circle around the darkest area of the image that will appear to select your background."); 


%initalize background selection.
app.bckimHan = imagesc(app.AverageIntensityMap, 'Parent', app.UIAxes4, 'XData', size(app.UIAxes4), 'YData', size(app.UIAxes4));

%Now this is to export the variables into the separate file to be able to
%select background because App Designer doesnt allow it.
app.DataOut.bckimHan = app.bckimHan;
app.AverageIntensityMap = mean(app.images, 3);
app.DataOut.AverageIntensityMap = app.AverageIntensityMap;
app.DataOut.calciumimages = app.calciumimages
app.DataOut.sx = app.sx;
app.DataOut.sy = app.sy;
app.DataOut.sz = app.sz;

DataOut = app.DataOut
save("vars.mat", 'DataOut'); %save your variables to import into files, struct DataOut


app.UIAxes_2.XTick = [app.st/2  app.ed/2];
app.UIAxes_2.XTickLabel = {num2str(app.st/2),   num2str(app.ed/2)};
   
%need axis limits to be increasing, so error handling here for that


background(app) %starts function for background selection now.

        end

        % Value changed function: TimeslicenumberSlider
        function TimeslicenumberSliderValueChanged(app, event)
            %takes the values from the slider
            app.newtime = app.TimeslicenumberSlider.Value
            if isinteger(app.newtime);
                app.newtime = app.newtime
            else
                app.newtime = round(app.newtime)
            end 
            %needs to round up to match time slice.
            %replot app is called.
            replot(app);
        end

        % Button pushed function: DoneButton_2
        function DoneButton_2Pushed(app, event)
%once the done button is clicked, the background will be subtracted from
%all 
hold(app.UIAxes_2,'off');
%imagesnosignal caclulated to subract from amplitude once cells are
%analyzed.
for i=1:app.sz
    app.imagesnosignal(:,:,i) = app.images(:,:,i).*app.bkgcell;
end

app.imagesnosignal(~find(app.imagesnosignal))=nan;
app.MeanNoSignalMask = squeeze(nanmean(nanmean(app.imagesnosignal,1),2));
app.DataOut.app.MeanNoSignalMask = app.MeanNoSignalMask;
app.MeanNoSignalMask0 = detrend(app.MeanNoSignalMask);
app.DataOut.app.MeanNoSignalMask0 = app.MeanNoSignalMask0;

app.averageamp = max(abs(app.MeanNoSignalMask0)); %max absolute value of the mean no signal mask

app.DataOut.averageamp = app.averageamp  %save av. amplitude for analysis later

 f6 = msgbox('Background Selected. Now select cells in the Figure 1 image');
 uiwait(f6);

%initializing the cell selection now
app.UIAxes6.XTick = app.UIAxes_2.XTick 
app.UIAxes6.XTickLabel = app.UIAxes_2.XTickLabel 

cellselection(app);

        end

        % Button pushed function: PlotSelectedBackgroundButton
        function PlotSelectedBackgroundButtonPushed(app, event)

%using bkgcell from other script, now we multiply that area by all the
%images in time dimension
for i=1:size(app.calciumimages,3);
    app.forplotting(:,:,i) = double(app.calciumimages(:,:,i)).* app.bkgcell;
end 

%forplotting has the pixels, the average of the x and y values are the
%intensities.
app.pixelintensities = squeeze(mean(mean(app.forplotting, 1),2));

%now plotting: first creating variables for plotting

time = app.T.'; %time needs to be transposed to plot

double(app.pixelintensities); %turn into double

[app.changebounds]  = [app.changebounds ; app.pixelintensities];
[minPx,maxPx] = bounds(app.changebounds);

app.UIAxes_2.YTick = [minPx maxPx];

app.UIAxes_2.YTickLabel = {num2str(minPx) , num2str(maxPx)};


plot(1:size(app.pixelintensities), app.pixelintensities, 'Parent', app.UIAxes_2); %plotting them into the axis
hold(app.UIAxes_2,'on');
clear minPx;
clear maxPx;
        end

        % Callback function
        function StartTimeEditFieldValueChanged(app, event)

 
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)

        end

        % Button pushed function: RedoCellSelectionButton
        function RedoCellSelectionButtonPushed(app, event)
            cellselection(app); %reruns cell selection if redo is pushed.
        end

        % Button pushed function: AnalyzeSelectedCellsButton
        function AnalyzeSelectedCellsButtonPushed(app, event)
%filtering images
h = fspecial('average', 5);
clear app.images
clear i
for i=1:app.T;
    app.images(:,:,i)= imfilter(app.calciumimages(:,:,i),h,'replicate');
end

%
%% analyze each cell
%cell is my counter
cellmask = app.cellmask;
for cell = 1:size(cellmask,3)

    indmask = cellmask(:,:,cell);
    for i=1:app.sz
        imagescell(:,:,i)=app.images(:,:,i).*indmask;
    end
    imagescell(~find(imagescell))=nan;
    Mean = squeeze(nanmean(nanmean(imagescell,1),2));
    app.DataOut.timecourse(cell,:) = Mean;
    app.DataOut.timecourseto0(cell,:) = detrend(Mean);

            %% Duty Cycle
    tccell = Mean;
    tclogical = find(detrend(Mean) > app.threshhold*app.averageamp);
    DataOut.dutycycle(cell,1) = length(tclogical)/length(tccell);
    %% Frequency
    s = Mean-mean(Mean); %use calcium to determine phase
    s = detrend(s);
    t = (0:length(s)-1); %time vector
    Ts = mean(diff(t)); %% Sampling period
    Fs = 1/Ts;  % Sampling Frequency
    Fn = Fs/2;  %Nyquist Frequency
    L  = length(s); %length of signal

    fts = fft(s-mean(s));
    course =s-mean(s);
    f = Fs*(0:(ceil(L/2)))/L;
    P2 = abs(fts/L);
    P1 = P2(1:(ceil(L/2)+1));
    P1(2:end-1) = 2*P1(2:end-1);

    amp_fts = abs(fts);      % Spectrum Amplitude
    phs_fts = angle(fts);
    highestfreq = f(find(P1==max(P1)));%this is the prob
    value = find(f==highestfreq);
    DataOut.freqvec(cell,1) = f(value);
    DataOut.amp(cell,1) = amp_fts(value);
    figure
    plot(t, Mean-mean(Mean), 'r');
    hold on
    plot((t),max(P1)*sin(2*pi*f(value) *(t)), 'b');
    title('Fourier Frequency')

end

figure
plot(app.DataOut.timecourseto0')
hold on
plot(ones(1,length(Mean))*app.threshhold*app.averageamp, 'k', 'LineWidth', 2);
hold on
plot(app.MeanNoSignalMask0','r', 'LineWidth', 2);
title('Duty Cycle')


figure
plot(app.DataOut.timecourse')
hold on
plot(ones(1,length(Mean))*app.threshhold*app.averageamp, 'k', 'LineWidth', 2);
hold on
plot(app.MeanNoSignalMask', 'r', 'LineWidth', 2);
title('Real Time Course');


 resultsfolder = 'results'; %folder to save results
 time = datestr(datetime('today'));

                 
%% %%%% Compile Numbers %%%% %%
%cell is the counter, m is second counter
m=1;
            for cell=1:size(DataOut.dutycycle,1);
               
                Data.cell{m,1} = ['cell' num2str(cell)];
                Data.dutycycle(m,1) = DataOut.dutycycle(cell,1);
                Data.freqvec(m,1) = DataOut.freqvec(cell,1);
                Data.amp(m,1) = DataOut.amp(cell,1);
                m = m+1;
            end
        

savename = [app.whichfile time];


saveAllFigsToPPT([app.path savename]) %saves ppt
close all  %closes the figs
savefile = [app.path savename];
T = struct2table(Data);
writetable(T,[savefile '.xlsx']);
save(savefile);

 msgbox(['Analysis successful for ' app.whichfile]);


        end

        % Button pushed function: NeedInstructionsButton
        function NeedInstructionsButtonPushed(app, event)
            %simple display of instructions if button clicked in
            %messagebox:
            
            line1_1 = '1. A messagebox will appear asking you to select your bfopen() toolbox.'
            line2_1 = 'If you don''t have bfopen() installed, please go to: https://www.openmicroscopy.org/bio-formats/downloads/. This is needed to read .czi files.' 
               
            line3_1 = '3. You need to wait a few seconds, and then you''ll be able to scroll through the slider and see yout file.'
            
             line4_1 = '4. Write the time you''d like to start analysis in Start Time also time you''d like to end the analysis in Time End.'
        
            line5_1 =  '5. Select which channel in your file contains the Calcium waves.'
             
             line6_1 = '6. Click Done when you''re ready.'

            msgbox({line1_1;line2_1;line3_1;line4_1;line5_1;line6_1})
        end

        % Button pushed function: NeedInstructionsButton_2
        function NeedInstructionsButton_2Pushed(app, event)
                  %simple display of instructions if button clicked in
            %messagebox:           
            line1_2 = ['1. A messagebox will appear asking you to select your background. "Figure 1" will appear with your image.'];
            
            line2_2 = ['Draw a circle around the darkest area in Figure 1. In your Background tab, the selected background will appear in the top right.'];
            
            line3_2 = ['3. You can vizualize the intesnity over time of your background by clicking the plot button.'];
            
            line4_2 = ['4. Redraw your circle if you are not satisfied with your selection.']
            
            line5_2 = ['5. Click Done when you''re ready.']

            msgbox({line1_2;line2_2;line3_2;line4_2;line5_2});
        end

        % Button pushed function: RedrawBackgroundButton
        function RedrawBackgroundButtonPushed(app, event)
            background(app);
        end

        % Button pushed function: NeedInstructionsButton_3
        function NeedInstructionsButton_3Pushed(app, event)

                 %simple display of instructions if button clicked in
            %messagebox:
            
 line3_1 = '1. A message box will appear asking you to select your cells in Figure 1 window.';

line3_2 = '2. Draw a circle around your first cell (click and hold).';

line3_3 = '3. You''ll be asked if you want to draw more cells or not. Click iNon when you have circled enough cells (2 or 3 is a good number to try things out).'

line3_4 = '4. Your selections will appear in the Selecting Cells tab. You can accept this selections, or re-select by clicking "Redo Cell Selection" button.'

line3_5 = '5. If you click "Analyze Selected Cells", the cells will be analyzed, and the results will be exported.'

line3_6 = '6. You will end with a “results” folder, and an Excel document with your analyzed data.'

msgbox({line3_1;line3_2;line3_3;line3_4;line3_5;line3_6});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalyzeCalciumImagingUIFigure = uifigure('Visible', 'off');
            app.AnalyzeCalciumImagingUIFigure.Name = 'Analyze Calcium Imaging ';
            app.TabGroup = uitabgroup(app.AnalyzeCalciumImagingUIFigure);
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
            app.UIAxes = uiaxes(app.TimeTab);
            title(app.UIAxes, 'Type Start Time and End Time below:')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TimeslicenumberSliderLabel = uilabel(app.TimeTab);
            app.TimeslicenumberSliderLabel.Text = 'Time (slice number)';
            app.TimeslicenumberSlider = uislider(app.TimeTab);
            app.TimeslicenumberSlider.ValueChangedFcn = createCallbackFcn(app, @TimeslicenumberSliderValueChanged, true);
            app.Write1ifyouwantwholefileanalyzedLabel = uilabel(app.TimeTab);
            app.Write1ifyouwantwholefileanalyzedLabel.Text = 'Write "-1" if you want whole file analyzed';
            app.DoneButton = uibutton(app.TimeTab, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.WhichChannelisCalciumDropDownLabel = uilabel(app.TimeTab);
            app.WhichChannelisCalciumDropDownLabel.Text = 'Which Channel is Calcium';
            app.WhichChannelisCalciumDropDown = uidropdown(app.TimeTab);
            app.WhichChannelisCalciumDropDown.Items = {'-', 'Channel 1', 'Channel 2'};
            app.WhichChannelisCalciumDropDown.Value = '-';
            app.StartTimeEditFieldLabel = uilabel(app.TimeTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.TimeTab, 'numeric');
            app.EndTimeEditFieldLabel = uilabel(app.TimeTab);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.TimeTab, 'numeric');
            app.NeedInstructionsButton = uibutton(app.TimeTab, 'push');
            app.NeedInstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @NeedInstructionsButtonPushed, true);
            app.NeedInstructionsButton.Text = 'Need Instructions?';
            app.BackgroundTab = uitab(app.TabGroup);
            app.BackgroundTab.Title = 'Background';
            app.UIAxes_2 = uiaxes(app.BackgroundTab);
            title(app.UIAxes_2, 'Intensity over Time')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Intensity')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes2 = uiaxes(app.BackgroundTab);
            title(app.UIAxes2, 'Channel 1')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.BackgroundTab);
            title(app.UIAxes3, 'Channel 2')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.BackgroundTab);
            title(app.UIAxes4, 'Selected Background:')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.DoneButton_2 = uibutton(app.BackgroundTab, 'push');
            app.DoneButton_2.ButtonPushedFcn = createCallbackFcn(app, @DoneButton_2Pushed, true);
            app.DoneButton_2.Text = 'Done';
            app.PlotSelectedBackgroundButton = uibutton(app.BackgroundTab, 'push');
            app.PlotSelectedBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedBackgroundButtonPushed, true);
            app.PlotSelectedBackgroundButton.Text = 'Plot Selected Background';
            app.NeedInstructionsButton_2 = uibutton(app.BackgroundTab, 'push');
            app.NeedInstructionsButton_2.ButtonPushedFcn = createCallbackFcn(app, @NeedInstructionsButton_2Pushed, true);
            app.NeedInstructionsButton_2.Text = 'Need Instructions?';
            app.RedrawBackgroundButton = uibutton(app.BackgroundTab, 'push');
            app.RedrawBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawBackgroundButtonPushed, true);
            app.RedrawBackgroundButton.Text = 'Redraw Background';
            app.SelectingCellsTab = uitab(app.TabGroup);
            app.SelectingCellsTab.Title = 'Selecting Cells';
            app.UIAxes5 = uiaxes(app.SelectingCellsTab);
            title(app.UIAxes5, 'All Cells Being Analyzed:')
            app.UIAxes5.XLim = [1 512];
            app.UIAxes5.YLim = [1 512];
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes5.ZTick = [];
            app.UIAxes6 = uiaxes(app.SelectingCellsTab);
            title(app.UIAxes6, 'Cells Intensity Over Time')
            xlabel(app.UIAxes6, 'Time')
            ylabel(app.UIAxes6, 'Pixel Intensity ')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.RedoCellSelectionButton = uibutton(app.SelectingCellsTab, 'push');
            app.RedoCellSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RedoCellSelectionButtonPushed, true);
            app.RedoCellSelectionButton.Text = 'Redo Cell Selection';
            app.AnalyzeSelectedCellsButton = uibutton(app.SelectingCellsTab, 'push');
            app.AnalyzeSelectedCellsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSelectedCellsButtonPushed, true);
            app.AnalyzeSelectedCellsButton.Text = 'Analyze Selected Cells';
            app.NeedInstructionsButton_3 = uibutton(app.SelectingCellsTab, 'push');
            app.NeedInstructionsButton_3.ButtonPushedFcn = createCallbackFcn(app, @NeedInstructionsButton_3Pushed, true);
            app.NeedInstructionsButton_3.Text = 'Need Instructions?';
            % Show the figure after all components are created
            app.AnalyzeCalciumImagingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_GUI_Analyze_Calcium

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalyzeCalciumImagingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalyzeCalciumImagingUIFigure)
        end
    end
end